classdef seeVieweR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OverlayHighVal               matlab.ui.control.NumericEditField
        OverlayLowVal                matlab.ui.control.NumericEditField
        BaseLowVal                   matlab.ui.control.NumericEditField
        BaseHighVal                  matlab.ui.control.NumericEditField
        PctHighOverlay               matlab.ui.control.Label
        PctLowBase                   matlab.ui.control.Label
        PctLowOverlay                matlab.ui.control.Label
        PctHighEditField_Overlay     matlab.ui.control.NumericEditField
        PctHighEditField_Base        matlab.ui.control.NumericEditField
        PctHighBase                  matlab.ui.control.Label
        PctLowEditField_Overlay      matlab.ui.control.NumericEditField
        PctLowEditField_Base         matlab.ui.control.NumericEditField
        TrimOverlayCheckBox          matlab.ui.control.CheckBox
        TrimBaseCheckBox             matlab.ui.control.CheckBox
        ApplySliceMaskDropDown       matlab.ui.control.DropDown
        ApplySliceMaskDropDownLabel  matlab.ui.control.Label
        Overlay_high                 matlab.ui.control.NumericEditField
        Base_high                    matlab.ui.control.NumericEditField
        Overlay_low                  matlab.ui.control.NumericEditField
        Base_low                     matlab.ui.control.NumericEditField
        ORI3_high                    matlab.ui.control.NumericEditField
        ORI2_high                    matlab.ui.control.NumericEditField
        ORI1_high                    matlab.ui.control.NumericEditField
        ORI3_low                     matlab.ui.control.NumericEditField
        ORI2_low                     matlab.ui.control.NumericEditField
        ORI1_low                     matlab.ui.control.NumericEditField
        ORI3Slider                   matlab.ui.control.RangeSlider
        ORI3SliderLabel              matlab.ui.control.Label
        ORI2Slider                   matlab.ui.control.RangeSlider
        ORI2SliderLabel              matlab.ui.control.Label
        ORI1Slider                   matlab.ui.control.RangeSlider
        ORI1SliderLabel              matlab.ui.control.Label
        OverlayAlphaSpinner          matlab.ui.control.Spinner
        OverlayAlphaSpinnerLabel     matlab.ui.control.Label
        BaseAlphaSpinner             matlab.ui.control.Spinner
        BaseAlphaSpinnerLabel        matlab.ui.control.Label
        OverlayThresholdLabel        matlab.ui.control.Label
        OverlaySlider                matlab.ui.control.RangeSlider
        BaseSlider                   matlab.ui.control.RangeSlider
        RenderingDropDownOverlay     matlab.ui.control.DropDown
        RenderingDropDown_2Label     matlab.ui.control.Label
        BaseThresholdLabel           matlab.ui.control.Label
        ThresholdSliderOverlay       matlab.ui.control.RangeSlider
        ColorbarPanelOverlay         matlab.ui.container.Panel
        FlipColormapDropDownOvelay   matlab.ui.control.DropDown
        FlipMapLabel_2               matlab.ui.control.Label
        ColormapDropDownOverlay      matlab.ui.control.DropDown
        OverlayMapLabel              matlab.ui.control.Label
        LoadOverlayButton            matlab.ui.control.Button
        ContrastSpinner              matlab.ui.control.Spinner
        ContrastSpinnerLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ProtectionThresholdSpinner   matlab.ui.control.Spinner
        ProtectionThresholdSpinnerLabel  matlab.ui.control.Label
        ExportImageButton            matlab.ui.control.Button
        ErodeBaseImageCheckBox       matlab.ui.control.CheckBox
        ErosionStrengthSpinner       matlab.ui.control.Spinner
        ErosionStrengthSpinnerLabel  matlab.ui.control.Label
        FlipColormapDropDown         matlab.ui.control.DropDown
        FlipMapLabel                 matlab.ui.control.Label
        DenoisingDropDown            matlab.ui.control.DropDown
        DenoisingDropDownLabel       matlab.ui.control.Label
        LightingDropDown             matlab.ui.control.DropDown
        LightingDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown           matlab.ui.control.DropDown
        BackgroundDropDownLabel      matlab.ui.control.Label
        ApplyBrainMaskCheckBox       matlab.ui.control.CheckBox
        ColorbarPanel                matlab.ui.container.Panel
        RenderingDropDown            matlab.ui.control.DropDown
        RenderingDropDownLabel       matlab.ui.control.Label
        TerritoryDropDown            matlab.ui.control.DropDown
        TerritoryDropDownLabel       matlab.ui.control.Label
        VisualizationPanel           matlab.ui.container.Panel
        ThresholdSlider              matlab.ui.control.RangeSlider
        ColormapDropDown             matlab.ui.control.DropDown
        ColorMapDropDownLabel        matlab.ui.control.Label
        LoadBaseButton               matlab.ui.control.Button
    end

    % Copyright (C) Alex A. Bhogal, 2021, University Medical Center Utrecht,
    % a.bhogal@umcutrecht.nl
    %
    % This program is free software: you can redistribute it and/or modify
    % it under the terms of the GNU General Public License as published by
    % the Free Software Foundation, either version 3 of the License, or
    % (at your option) any later version.
    %
    % This program is distributed in the hope that it will be useful,
    % but WITHOUT ANY WARRANTY; without even the implied warranty of
    % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    % GNU General Public License for more details.
    %
    % You should have received a copy of the GNU General Public License
    % along with this program.  If not, see <https://www.gnu.org/licenses/>.

    properties (Access = private)
        seeVRPath  = []   % Path to seeVR toolbox
        dataImage  = []   % Stores the loaded volume data
        sourceDataImage = [] % keep the original
        voxelSize  = []   % Stores voxel size information
        viewer     = []   % Stores viewer3d object
        volHandle  = []   % Stores volshow object handle
        path       = []   % Path to data
        atlasImage = []   % full atlas
        masks      = []   % Terriory masks
        WBmask     = []   % Whole brain mask
        vesselSkeleton = []   %Vessel Skeleton
        existMasks = 0    % bolean
        overlayImage = [] % Overlay Image
        sourceOverlayImage = [] % keep the original
        overlay = 0;      % bolean
        hfig       = []   % Viewer Object
        tform      = []   % Transform matrix
        niftiInfoBase = []% Info for base image
        basePath   = []   % Path for base image
        baseHistCounts = []  % 1×N   counts  (base volume)
        baseHistEdges = []   % 1×N+1 bin-edges
        overlayHistCounts= []
        overlayHistEdges= []
    end

    methods (Access = private)

        function updateVisualization(app)
            %% --- Fixed global intensity range
            baseRange = app.BaseSlider.UserData;
            fixedMin = baseRange(1) + app.BaseSlider.Value(1) * (baseRange(2) - baseRange(1));
            fixedMax = baseRange(1) + app.BaseSlider.Value(2) * (baseRange(2) - baseRange(1));

            colormapResolution = 256;

            %% --- Base image normalization
            imageData = app.dataImage;
            normImage = (imageData - fixedMin) / (fixedMax - fixedMin);
            normImage = max(min(normImage, 1), 0);  % clip only

            %% --- Threshold mask (controls visibility)
            thrRange = round(app.ThresholdSlider.Value);
            thrMin = thrRange(1);
            thrMax = thrRange(2);
            thresholdMask = (imageData >= thrMin) & (imageData <= thrMax);

            %% --- Region mask
            if app.existMasks
                switch app.TerritoryDropDown.Value
                    case 'ALL'
                        regionMask = app.atlasImage > 0;
                    case 'Anterior_L'
                        regionMask = app.masks.Anterior_L;
                    case 'Anterior_R'
                        regionMask = app.masks.Anterior_R;
                    case 'PCA'
                        regionMask = app.masks.PCA;
                    case 'VB'
                        regionMask = app.masks.VB;
                    case 'Ventricles'
                        regionMask = app.masks.Ventricles;
                    otherwise
                        regionMask = true(size(imageData));
                end
            else
                regionMask = true(size(imageData));
            end

            %% --- Combine masks
            visibilityMask = thresholdMask & regionMask;
            if app.ApplyBrainMaskCheckBox.Value
                visibilityMask = visibilityMask & app.WBmask;
            end

            %% --- Erosion
            if app.ErodeBaseImageCheckBox.Value
                vesselMask = visibilityMask;
                vesselSkel = app.vesselSkeleton;
                skelProtection = imdilate(vesselSkel, strel('sphere', app.ProtectionThresholdSpinner.Value));
                distMap = bwdist(~vesselMask);
                distNorm = distMap / max(distMap(:));
                erosionLevel = app.ErosionStrengthSpinner.Value;
                erodeMask = distNorm > erosionLevel;
                visibilityMask = (erodeMask | skelProtection) & vesselMask;
            end

            %% Slice sliders
            %% ----------- Slice-masks -------------------------------------------------
            % Build one single geometrical mask from the three range sliders
            xLim = app.ORI1Slider.Value;   yLim = app.ORI2Slider.Value;   zLim = app.ORI3Slider.Value;
            xSize = app.ORI1Slider.UserData;  ySize = app.ORI2Slider.UserData;  zSize = app.ORI3Slider.UserData;
            xIdx  = max(1,min(round([xLim(1) xLim(2)]),xSize));
            yIdx  = max(1,min(round([yLim(1) yLim(2)]),ySize));
            zIdx  = max(1,min(round([zLim(1) zLim(2)]),zSize));

            sliceMask = false(size(app.dataImage));
            sliceMask(yIdx(1):yIdx(2), xIdx(1):xIdx(2), zIdx(1):zIdx(2)) = true;

            % Decide where this mask should be applied -------------------------------
            switch app.ApplySliceMaskDropDown.Value
                case 'Base'
                    sliceMaskBase    = sliceMask;           % only base is clipped
                    sliceMaskOverlay = true(size(sliceMask));
                case 'Overlay'
                    sliceMaskBase    = true(size(sliceMask));
                    sliceMaskOverlay = sliceMask;           % only overlay is clipped
                otherwise  % 'All'
                    sliceMaskBase    = sliceMask;
                    sliceMaskOverlay = sliceMask;
            end

            visibilityMask = visibilityMask & sliceMaskBase;

            %% --- Final masked image (color clipped, but visible where mask == true)
            maskedImage = normImage;
            maskedImage(~visibilityMask) = 0;

            %% --- Lock colormap scaling
            maskedImage(1,1,1) = 0;
            maskedImage(1,1,2) = 1;

            %% --- Colormap and alpha
            colmap = app.getCustomColormap(app.ColormapDropDown.Value);
            if strcmp(app.FlipColormapDropDown.Value,'yes')
                colmap = flip(colmap);
            end
            alphaMap = (linspace(0, 1, colormapResolution).^app.ContrastSpinner.Value) * app.BaseAlphaSpinner.Value;
            alphaMap = min(1, alphaMap);
            alphaMap(1) = 0;

            %% --- Overlay preparation (if exists)
            if app.overlay
                overlayData = app.overlayImage;

                if app.ApplyBrainMaskCheckBox.Value
                    overlayData = overlayData.*app.WBmask;
                end

                % Get true intensity range from stored UserData
                trueMin = app.OverlaySlider.UserData(1);
                trueMax = app.OverlaySlider.UserData(2);

                % Map normalized slider values back to intensity range
                oMin = trueMin + app.OverlaySlider.Value(1) * (trueMax - trueMin);
                oMax = trueMin + app.OverlaySlider.Value(2) * (trueMax - trueMin);
                normOverlay = (overlayData - oMin) / (oMax - oMin);
                normOverlay = max(min(normOverlay,1),0);
                normOverlay(~isfinite(normOverlay)) = NaN;   % restore NaNs after clamp

                % Apply threshold and slice mask to overlay
                thrOverlay = round(app.ThresholdSliderOverlay.Value);
                maskOverlay = (overlayData >= thrOverlay(1)) & (overlayData <= thrOverlay(2)) & sliceMaskOverlay;
                normOverlay(~maskOverlay) = 0;

                normOverlay(1,1,1) = 0;
                normOverlay(1,1,2) = 1;

                colmapOverlay = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
                if strcmp(app.FlipColormapDropDownOvelay.Value,'yes')
                    colmapOverlay = flip(colmapOverlay);
                end

                sliderAlpha = app.OverlayAlphaSpinner.Value;

                % Create default alpha map
                alphaOverlay = linspace(0, 1, colormapResolution) * sliderAlpha;

                % Map zero overlay values to alpha = 0
                overlayDataClipped = min(max(normOverlay, 0), 1);  % just to be safe

                % Find colormap bin that corresponds to 0 intensity
                zeroIdx = round((0 - oMin) / (oMax - oMin) * (colormapResolution - 1)) + 1;
                zeroIdx = max(1, min(colormapResolution, zeroIdx));  % clamp to valid index
                alphaOverlay(zeroIdx) = 0;
            end

            %% --- Reset viewer
            if ~isempty(app.viewer) && isgraphics(app.viewer)
                camPos    = app.viewer.CameraPosition;
                camTarget = app.viewer.CameraTarget;
                camUp     = app.viewer.CameraUpVector;
                camZoom   = app.viewer.CameraZoom;
                delete(app.viewer);
            else
                volSize = size(app.dataImage);
                centerVoxel = [volSize(2), volSize(1), volSize(3)] / 2;
                camTarget = centerVoxel .* [app.voxelSize(2), app.voxelSize(1), app.voxelSize(3)];
                camPos = camTarget + [300 0 0];
                camUp = [0 0 1]; camZoom = 1.5;
            end

            app.viewer = viewer3d(Parent=app.VisualizationPanel);
            app.viewer.CameraPosition = camPos;
            app.viewer.CameraTarget = camTarget;
            app.viewer.CameraUpVector = camUp;
            app.viewer.CameraZoom = camZoom;
            app.viewer.BackgroundColor = strcmp(app.BackgroundDropDown.Value,'Dark') * [0 0 0] + strcmp(app.BackgroundDropDown.Value,'Light') * [0.9 0.9 0.9];
            app.viewer.GradientColor = [0.5 0.5 0.5];
            app.viewer.RenderingQuality = 'high';
            app.viewer.ScaleBar = 'off';
            app.viewer.Denoising = app.DenoisingDropDown.Value;
            app.viewer.Lighting = app.LightingDropDown.Value;

            %% --- Render base + optional overlay
            if ~app.overlay
                app.volHandle = volshow(maskedImage, ...
                    Parent=app.viewer, Colormap=colmap, Alphamap=alphaMap, ...
                    Transformation=app.tform, RenderingStyle=app.RenderingDropDown.Value);
            else
                app.volHandle = volshow(maskedImage, ...
                    Parent=app.viewer, Colormap=colmap, Alphamap=alphaMap, ...
                    Transformation=app.tform, RenderingStyle=app.RenderingDropDown.Value, ...
                    OverlayData=normOverlay, OverlayColormap=colmapOverlay, ...
                    OverlayRenderingStyle=app.RenderingDropDownOverlay.Value, ...
                    OverlayAlphamap=alphaOverlay);
            end
            %save for export

            %% --- Render base colorbar
            colormapRGB = uint8(255 * permute(colmap, [1 3 2]));
            cbarImage = repmat(colormapRGB, 1, app.ColorbarPanel.Position(3));
            persistent colorbarAxes
            if isempty(colorbarAxes) || ~isvalid(colorbarAxes)
                colorbarAxes = axes('Parent', app.ColorbarPanel, 'Units', 'normalized', 'Position', [0 0 1 1], 'Visible', 'off');
                colorbarAxes.Interactions = [];
            end
            cla(colorbarAxes); image(colorbarAxes, cbarImage);
            axis(colorbarAxes, 'tight'); set(colorbarAxes, 'Visible', 'off', 'YDir', 'normal');

            %% --- Overlay colorbar
            if app.overlay
                overlayRGB = uint8(255 * permute(colmapOverlay, [1 3 2]));
                overlayBar = repmat(overlayRGB, 1, app.ColorbarPanelOverlay.Position(3));
                persistent overlayAxes
                if isempty(overlayAxes) || ~isvalid(overlayAxes) ...
                        || overlayAxes.Parent ~= app.ColorbarPanelOverlay
                    overlayAxes = axes('Parent', app.ColorbarPanelOverlay, 'Units', 'normalized', 'Position', [0 0 1 1], 'Visible', 'off');
                    overlayAxes.Interactions = [];
                end
                cla(overlayAxes); image(overlayAxes, overlayBar);
                axis(overlayAxes, 'tight'); set(overlayAxes, 'Visible', 'off', 'YDir', 'normal');
            end

            %% ------------------------------------------------------------------
            %% >>>     Draw STATIC histograms, always 256 rows               <<<
            %% ------------------------------------------------------------------
            barW = 0.85;                 % white-bar length as fraction of bar width
            nRows = 256;                 % one row per colour

            % helper – fast re‐scale to 256 rows *once*, then just plot it
            function drawStaticHist(ax, counts256)
                counts256 = counts256 ./ max(counts256);           % 0…1
                cla(ax);   hold(ax,'on');
                for r = 1:nRows
                    if counts256(r)
                        line(ax,[0 counts256(r)*barW],[r r], ...
                            'Color','w','LineWidth',1,'HitTest','off');
                    end
                end
                hold(ax,'off');
            end

            % ---------------- BASE ---------------------------------------------
            persistent hHistBase
            if isempty(hHistBase) || ~isgraphics(hHistBase)
                hHistBase = axes('Parent',app.ColorbarPanel, ...
                    'Units','normalized','Position',[0 0 1 1], ...
                    'Color','none','XColor','none','YColor','none', ...
                    'YDir','normal','HitTest','off');
            end
            if ~isempty(app.baseHistCounts)
                drawStaticHist(hHistBase, app.baseHistCounts);
            end

            % ---------------- OVERLAY  (only if one is loaded) ------------------
            if app.overlay
                persistent hHistOv
                if isempty(hHistOv) || ~isgraphics(hHistOv) || ...
                        hHistOv.Parent ~= app.ColorbarPanelOverlay
                    hHistOv = axes('Parent',app.ColorbarPanelOverlay, ...
                        'Units','normalized','Position',[0 0 1 1], ...
                        'Color','none','XColor','none','YColor','none', ...
                        'YDir','normal','HitTest','off');
                end
                if ~isempty(app.overlayHistCounts)
                    drawStaticHist(hHistOv, app.overlayHistCounts);
                end
            end


        end

        function cmap = getCustomColormap(app, cmapName)
            low_val = round(0.2 * 256);
            high_val = 256 - low_val;  % remaining entries

            switch cmapName
                case 'Spectral'
                    cmap = brewermap(256, 'Spectral');
                case 'RdYlBl'
                    cmap = brewermap(256, 'RdYlBu');
                case 'RdYlGn'
                    cmap = brewermap(256, 'RdYlGn');
                case 'Inferno'
                    cmap = inferno(256);
                case 'Turbo'
                    cmap = turbo(256);
                case 'Devon'
                    cmap = crameri('devon', 256);
                case 'grayC'
                    cmap = crameri('grayC', 256);
                case 'Hawaii'
                    cmap = crameri('hawaii', 256);
                case 'Viridis'
                    cmap = viridis(256);
                case 'Magma'
                    cmap = magma(256);
                case 'Plasma'
                    cmap = plasma(256);
                case 'Lajolla'
                    cmap = crameri('lajolla', 256);
                case 'Buda'
                    cmap = crameri('buda', 256);
                case 'Nuuk'
                    cmap = crameri('nuuk', 256);
                case 'Roma'
                    cmap = crameri('roma', 256);
                case 'DSA'
                    cmap = flipud(gray(256));

                otherwise
                    cmap = parula(256);  % fallback
            end
        end

        %-------------------------------------------------------
        function s = val2slider(app, v, rng)
            % real-value → slider-fraction   (clamped 0…1)
            s = (v - rng(1)) ./ diff(rng);
            s = max(0, min(1, s));
        end
        %-------------------------------------------------------
        function v = slider2val(app, s, rng)
            % slider-fraction → real-value
            v = rng(1) + s .* diff(rng);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [toolbox,datadir] = definePaths();
            app.seeVRPath = toolbox;

            if exist(toolbox) == 7
                addpath(genpath(toolbox));
            else
                error('define path to seeVR toolbox in definePaths function')
            end

            if exist(datadir) == 7
            else
                datadir = pwd;
            end

            cd(datadir);

            % Create Colorbar Panel
            viewerPos = app.VisualizationPanel.Position;
            colorbarX = viewerPos(1) + viewerPos(3) + 10;
            colorbarY = viewerPos(2);
            colorbarW = 40;
            colorbarH = viewerPos(4);
            app.ColorbarPanel = uipanel(app.UIFigure);
            app.ColorbarPanel.Position = [colorbarX, colorbarY, colorbarW, colorbarH];

        end

        % Button pushed function: LoadBaseButton
        function LoadBaseButtonPushed(app, event)
            app.dataImage = [];
            app.sourceDataImage = [];
            [file, path] = uigetfile('*.nii;*.nii.gz');
            if isequal(file, 0)
                return;
            end

            niftiData = niftiread(fullfile(path, file));
            niftiInfo = niftiinfo(fullfile(path, file));
            app.niftiInfoBase = niftiInfo;
            app.voxelSize = niftiInfo.PixelDimensions;
            app.basePath = fullfile(path, file);


            %% --- Transformation matrix
            sx = app.voxelSize(2); sy = app.voxelSize(1); sz = app.voxelSize(3);
            app.tform = affinetform3d([sx 0 0 0; 0 sy 0 0; 0 0 sz 0; 0 0 0 1]);
            rawImage = double(niftiData); rawImage(rawImage == 0) = NaN;

            % If dynamic range is too large, rescale image
            if max(rawImage(:)) > 1e5
                rescaleBase = 1;
                disp('Input image has large intensity range. Rescaling to [0, 1000] for stability.');
                rawImage = 1000 * (rawImage - min(rawImage(:))) / (max(rawImage(:)) - min(rawImage(:)));
                disp('... possibly anatomic image, setting visualization parameters')
                app.ColormapDropDown.Value = 'grayC';
            else
                rescaleBase = 0;
            end

            app.dataImage = rawImage;
            app.sourceDataImage = app.dataImage; %save reference base

            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.dataImage);
            edges           = linspace(min(app.dataImage(valid)),max(app.dataImage(valid)),nBins+1);
            app.baseHistCounts = histcounts(app.dataImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.baseHistEdges = edges;

            % Binary vessel mask from original data
            vesselBinary = app.dataImage > 0;

            % Generate 3D skeleton (can take some time)
            if size(vesselBinary, ndims(vesselBinary)) > 1
                vesselBinary = squeeze(vesselBinary(:,:,:,end));
                app.vesselSkeleton = bwskel(vesselBinary);
            end

            cd(path);
            app.path = path;

            % Setup OverlaySlider (used for visual scaling of overlay colors)
            baseData = app.dataImage(:);
            baseData = baseData(~isnan(baseData) & ~isinf(baseData));  % remove NaN/Inf

            minBase = min(baseData);
            maxBase = max(baseData);
            if minBase == maxBase
                minBase = minBase - 1;
                maxBase = maxBase + 1;
            end

            app.BaseSlider.UserData = [minBase, maxBase];
            app.BaseSlider.Limits = [0, 1];

            if rescaleBase
                rescaledValid = rawImage(~isnan(rawImage) & ~isinf(rawImage));
                nbins = 1000;
                [counts, edges] = histcounts(rescaledValid, nbins);
                % Step 3: Find high-density region using cumulative sum
                cdf = cumsum(counts) / sum(counts);
                % Define adaptive clipping thresholds (e.g., keep 99% of mass)
                lowerIdx = find(cdf >= 0.005, 1, 'first'); % first bin above 0.5%
                upperIdx = find(cdf >= 0.995, 1, 'first'); % first bin above 99.5%
                % Convert bin indices to intensity values
                lowerBound = edges(lowerIdx);
                upperBound = edges(min(upperIdx, length(edges)-1));  % avoid out-of-bounds
                % Set normalized initial values (0–1 range)
                app.BaseSlider.Value = [lowerBound/maxBase upperBound/maxBase];
            else

                % Set the actual min/max for slider (rescaled to 0–1)
                app.BaseSlider.Limits = [0 1];

                % Set initial overlay visualization range (10% to 80%)
                baseMinVal = minBase + 0.1 * (maxBase - minBase);
                baseMaxVal = minBase + 0.8 * (maxBase - minBase);

                % Set normalized initial values (0–1 range)
                app.BaseSlider.Value = [(baseMinVal - minBase) / (maxBase - minBase), ...
                    (baseMaxVal - minBase) / (maxBase - minBase)];
            end

            %% Threshold Slider
            validVox          = isfinite(app.dataImage);        % no NaN / Inf
            trueMin           = round(min(app.dataImage(validVox)),2);
            trueMax           = round(max(app.dataImage(validVox)),2);

            app.ThresholdSlider.Limits = [trueMin , trueMax];
            app.ThresholdSlider.Value = [trueMin + abs(0.001*trueMin) , trueMax-0.001*trueMax];
            app.ThresholdSlider.Visible = 'on';

            %set value boxes
            app.Base_low.Limits = [trueMin, trueMax-0.001*trueMax];
            app.Base_high.Limits = [trueMin+0.001*trueMin, trueMax];
            app.Base_low.Value = app.ThresholdSlider.Value(1);
            app.Base_high.Value = app.ThresholdSlider.Value(2);

            rng    = app.BaseSlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.BaseSlider.Value, rng);
            app.BaseLowVal.Value = vals(1);
            app.BaseHighVal.Value = vals(2);

            %% Load arterial atlas mask
            atlasPath = fullfile(path, 'structToMNI', 'Inverse', 'ArterialAtlas_level2_toInput.nii.gz');
            if exist(atlasPath, 'file')
                atlas = niftiread(atlasPath);
                app.atlasImage = double(atlas);

                territoryIndices = struct( ...
                    'Anterior_L', [1, 3], ...
                    'Anterior_R', [2, 4], ...
                    'PCA',        [5, 6], ...
                    'VB',         [7, 8], ...
                    'Ventricles', [9, 10] ...
                    );

                masks = struct();
                for name = fieldnames(territoryIndices)'
                    masks.(name{1}) = ismember(app.atlasImage, territoryIndices.(name{1}));
                end
                app.masks = masks;
                app.TerritoryDropDown.Visible = 'on';
                app.TerritoryDropDownLabel.Visible = 'on';
                app.existMasks = 1;
                % set slice mask options
            end

            %% Load brain mask
            brainMaskFile = ls(fullfile(path, '*_brain_mask.nii*'));
            try
                if exist(fullfile(path, brainMaskFile), 'file')
                    WBmask = niftiread(fullfile(path, brainMaskFile));
                    app.WBmask = double(WBmask);
                    app.ApplyBrainMaskCheckBox.Visible = 'on';
                end
            catch
                disp('Could not load brain mask...')
            end

            %% Only initialize viewer once
            if isempty(app.viewer) %|| ~isvalid(app.viewer)
                app.viewer.CameraZoom = 1.5;
                app.viewer.GradientColor = [0.5 0.5 0.5];
                app.viewer.BackgroundColor = [0 0 0];
                app.viewer.RenderingQuality = 'high';
                app.viewer.ScaleBar = 'off';
                app.viewer.Denoising = app.DenoisingDropDown.Value;
                app.viewer.Lighting = app.LightingDropDown.Value;
            end

            %% Slice Slider

            volSize = size(app.dataImage);

            % Set slider limits to normalized range [0, 1]
            app.ORI1Slider.Limits = [1 volSize(2)];
            app.ORI2Slider.Limits = [1 volSize(1)];
            app.ORI3Slider.Limits = [1 volSize(3)];

            % Set full range (to initially show entire volume)
            app.ORI1Slider.Value = [1 volSize(2)];
            app.ORI2Slider.Value = [1 volSize(1)];
            app.ORI3Slider.Value = [1 volSize(3)];

            % Store volume size for later slicing use
            app.ORI1Slider.UserData = volSize(2);  % X = columns
            app.ORI2Slider.UserData = volSize(1);  % Y = rows
            app.ORI3Slider.UserData = volSize(3);  % Z = slices

            app.ORI1_low.Limits = [1 volSize(2)-1];
            app.ORI2_low.Limits = [1 volSize(1)-1];
            app.ORI3_low.Limits = [1 volSize(3)-1];

            app.ORI1_high.Limits = [2 volSize(2)];
            app.ORI2_high.Limits = [2 volSize(1)];
            app.ORI3_high.Limits = [2 volSize(3)];

            app.ORI1_low.Value = 1; app.ORI1_high.Value = volSize(2);
            app.ORI2_low.Value = 1; app.ORI2_high.Value = volSize(1);
            app.ORI3_low.Value = 1; app.ORI3_high.Value = volSize(3);

            %% UI Visibility
            app.BaseSlider.Visible = "on";
            app.BaseSlider.Position(2) = app.VisualizationPanel.Position(2);
            app.BaseSlider.Position(4) = app.VisualizationPanel.Position(4);
            app.ColorbarPanel.Visible = 'on';
            app.ThresholdSlider.Visible = 'on';
            app.BaseThresholdLabel.Visible = 'on';
            app.Base_low.Visible = "on";
            app.Base_high.Visible = "on";
            app.ExportImageButton.Visible = 'on';
            app.LoadOverlayButton.Visible = 'on';
            app.ORI1Slider.Visible = 'on';
            app.ORI1SliderLabel.Visible = "on";
            app.ORI2Slider.Visible = 'on';
            app.ORI2SliderLabel.Visible = "on";
            app.ORI3Slider.Visible = 'on';
            app.ORI3SliderLabel.Visible = "on";
            app.BaseAlphaSpinner.Visible = 'on';
            app.BaseAlphaSpinnerLabel.Visible = 'on';
            app.TrimBaseCheckBox.Visible = "on";
            app.BaseHighVal.Visible = "on";
            app.BaseLowVal.Visible = "on";
            %% Refresh Visualization
            app.updateVisualization();
        end

        % Button pushed function: LoadOverlayButton
        function LoadOverlayButtonPushed(app, event)
            app.overlayImage = [];
            app.sourceOverlayImage = [];
            [file,path] = uigetfile('*.nii;*.nii.gz');
            if isequal(file,0)
                return;
            end

            baseInfo = app.niftiInfoBase;
            baseVol  = app.dataImage;
            movInfo  = niftiinfo(fullfile(path,file));
            movVol   = niftiread(movInfo);
            % can change interpolation: cubic, spline, linear, nearest
            % neighbor
            aligned = overlay_to_base(baseVol, baseInfo, movVol, movInfo, 'cubic');
            aligned(aligned == 0) = NaN;

            % If dynamic range is too large, rescale image
            if max(aligned(:)) > 1e5
                disp('Input image has large intensity range. Rescaling to [0, 1000] for stability.');
                aligned = 1000 * (aligned - min(aligned(:))) / (max(aligned(:)) - min(aligned(:)));
                disp('... possibly anatomic image, setting visualization parameters')
                app.ColormapDropDownOverlay.Value = 'grayC';
                rescaleOverlay = 1;
            else
                rescaleOverlay = 0;
            end

            app.overlayImage = aligned;
            app.sourceOverlayImage = app.overlayImage; %save reference overlay

            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.overlayImage);
            edges           = linspace(min(app.overlayImage(valid)),max(app.overlayImage(valid)),nBins+1);
            app.overlayHistCounts = histcounts(app.overlayImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.overlayHistEdges = edges;

            try
                app.FlipColormapDropDownOvelay.Visible = 'on';
                app.ColormapDropDownOverlay.Visible = "on";
                app.FlipMapLabel_2.Visible = 'on';
                app.OverlayMapLabel.Visible = 'on';
                app.overlay = 1;
                app.RenderingDropDownOverlay.Visible = "on";
                app.RenderingDropDown_2Label.Visible = "on";

                % Setup OverlaySlider (used for visual scaling of overlay colors)
                overlayData = app.overlayImage(:);
                overlayData = overlayData(~isnan(overlayData) & ~isinf(overlayData));  % remove NaN/Inf

                validVox          = isfinite(overlayData);        % no NaN / Inf
                minBase           = round(min(overlayData(validVox)),2);
                maxBase           = round(max(overlayData(validVox)),2);

                clear overlayData;

                % Store the true min/max for normalization later
                app.OverlaySlider.UserData = [minBase, maxBase];  % store for later use
                % Set the actual min/max for slider (rescaled to 0–1)
                app.OverlaySlider.Limits = [0 1];

                if rescaleOverlay
                    rescaledValid = app.overlayImage(~isnan(app.overlayImage) & ~isinf(app.overlayImage));
                    nbins = 1000;
                    [counts, edges] = histcounts(rescaledValid, nbins);
                    % Step 3: Find high-density region using cumulative sum
                    cdf = cumsum(counts) / sum(counts);
                    % Define adaptive clipping thresholds (e.g., keep 99% of mass)
                    lowerIdx = find(cdf >= 0.005, 1, 'first'); % first bin above 0.5%
                    upperIdx = find(cdf >= 0.995, 1, 'first'); % first bin above 99.5%
                    % Convert bin indices to intensity values
                    lowerBound = edges(lowerIdx);
                    upperBound = edges(min(upperIdx, length(edges)-1));  % avoid out-of-bounds
                    % Set normalized initial values (0–1 range)
                    app.OverlaySlider.Value = double([lowerBound/maxBase upperBound/maxBase]);
                else

                    % Set the actual min/max for slider (rescaled to 0–1)
                    app.OverlaySlider.Limits = [0 1];

                    % Set initial overlay visualization range (10% to 80%)
                    overlayMinVal = minBase + 0.1 * (maxBase - minBase);
                    overlayMaxVal = minBase + 0.8 * (maxBase - minBase);

                    % Set normalized initial values (0–1 range)
                    app.OverlaySlider.Value = [(overlayMinVal - minBase) / (maxBase - minBase), ...
                        (overlayMaxVal - minBase) / (maxBase - minBase)];
                end

                % Make the slider visible
                app.OverlaySlider.Visible = 'on';

                %Setup threshold slider
                minLimit = double(round(min(app.overlayImage(:))));
                maxLimit = double(round(max(app.overlayImage(:))));
                app.ThresholdSliderOverlay.Limits = [minLimit, maxLimit];

                % Set symmetric value range around zero if bipolar

                if min(app.overlayImage(:)) < 0 && max(app.overlayImage(:)) > 0
                    maxAbs = max(abs(min(app.overlayImage(:))), abs(max(app.overlayImage(:))));
                    valLow = double(max(-maxAbs, minLimit));  % Don't go below slider limit
                    valHigh = double(min(maxAbs, maxLimit));  % Don't go above slider limit
                    app.ThresholdSliderOverlay.Value = [valLow, valHigh];
                    app.ThresholdSliderOverlay.MajorTicks = [valLow, valHigh];
                    app.ThresholdSliderOverlay.MajorTickLabels = {num2str(valLow), num2str(valHigh)};
                else
                    app.ThresholdSliderOverlay.Value = [minLimit, maxLimit];
                    app.ThresholdSliderOverlay.MajorTicks = [minLimit, maxLimit];
                    app.ThresholdSliderOverlay.MajorTickLabels =  {num2str(minLimit), num2str(maxLimit)};
                end

                %set value boxes
                app.Overlay_low.Limits = [minLimit, maxLimit-0.001*maxLimit];
                app.Overlay_high.Limits = [minLimit+abs(0.001*minLimit), maxLimit];
                app.Overlay_low.Value = app.ThresholdSliderOverlay.Value(1);
                app.Overlay_high.Value = app.ThresholdSliderOverlay.Value(2);
                app.Overlay_low.Visible = 'on';
                app.Overlay_high.Visible = 'on';

                rng    = app.OverlaySlider.UserData;               % [trueMin trueMax]
                vals   = slider2val(app, app.OverlaySlider.Value, rng);
                app.OverlayLowVal.Value = vals(1);
                app.OverlayHighVal.Value = vals(2);

                % Position of first colorbar (already defined)
                viewerPos = app.VisualizationPanel.Position;
                colorbarX = viewerPos(1) + viewerPos(3) + 10;
                colorbarY = viewerPos(2);
                colorbarW = 40;
                colorbarH = viewerPos(4);

                % Position of second colorbar (to the right of the first)
                gapBetweenBars = 20;
                overlayX = colorbarX + colorbarW + gapBetweenBars;

                app.ColorbarPanelOverlay = uipanel(app.UIFigure);
                app.ColorbarPanelOverlay.Position = [overlayX, colorbarY, colorbarW, colorbarH];
                app.ColorbarPanelOverlay.BackgroundColor = [0 0 0];
                % Match OverlaySlider to the overlay colorbar position
                sliderW = 3;
                app.OverlaySlider.Position = [overlayX + colorbarW + 2, colorbarY, sliderW, colorbarH];
                app.OverlaySlider.Visible = 'on';
                app.ColorbarPanelOverlay.Visible = 'on';
                app.OverlayAlphaSpinner.Visible = 'on';
                app.OverlayAlphaSpinnerLabel.Visible = 'on';
                app.ThresholdSliderOverlay.Visible = "on";
                app.OverlayThresholdLabel.Visible = "on";
                app.ApplySliceMaskDropDown.Items = {'All','Base','Overlay'};
                app.TrimOverlayCheckBox.Visible = "on";
                app.OverlayHighVal.Visible = "on";
                app.OverlayLowVal.Visible = "on";
            catch
                error('... overlay image appears to have a different matrix size than base image')
                app.ColorbarPanelOverlay.Visible = 'off';
                app.FlipColormapDropDownOvelay.Visible = 'off';
                app.ColormapDropDownOverlay.Visible = "off";
                app.FlipMapLabel_2.Visible = 'off';
                app.OverlayMapLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = 'off';
                app.RenderingDropDownOverlay.Visible = "off";
                app.OverlaySlider.Visible = 'off';
                app.OverlayAlphaSpinner.Visible = 'off';
                app.OverlayAlphaSpinnerLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = "off";
                app.OverlayThresholdLabel.Visible = "off";
                app.Overlay_low.Visible = 'off';
                app.Overlay_high.Visible = 'off';
                app.overlay = 0;
                app.ApplySliceMaskDropDown.Value = 'All';
                app.ApplySliceMaskDropDown.Items = {'All'};
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.TrimOverlayCheckBox.Visible = "off";
                app.OverlayHighVal.Visible = "off";
                app.OverlayLowVal.Visible = "off";
            end
            app.updateVisualization();
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.Base_low.Value = round(app.ThresholdSlider.Value(1),2);
            app.Base_high.Value = round(app.ThresholdSlider.Value(2),2);
            app.updateVisualization();
        end

        % Value changed function: ThresholdSliderOverlay
        function ThresholdSliderOverlayValueChanged(app, event)
            app.Overlay_low.Value = round(app.ThresholdSliderOverlay.Value(1),2);
            app.Overlay_high.Value = round(app.ThresholdSliderOverlay.Value(2),2);
            app.updateVisualization();
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            rng    = app.BaseSlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.BaseSlider.Value, rng);
            app.BaseLowVal.Value  = round(vals(1),2);
            app.BaseHighVal.Value = round(vals(2),2);

            app.updateVisualization();
        end

        % Value changed function: OverlaySlider
        function OverlaySliderValueChanged(app, event)
            rng    = app.OverlaySlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.OverlaySlider.Value, rng);
            app.OverlayLowVal.Value  = round(vals(1),2);
            app.OverlayHighVal.Value = round(vals(2),2);

            app.updateVisualization();
        end

        % Size changed function: VisualizationPanel
        function VisualizationPanelSizeChanged(app, event)
            app.viewer = viewer3d('Parent', app.VisualizationPanel);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: TerritoryDropDown
        function TerritoryDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: RenderingDropDown
        function RenderingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ApplyBrainMaskCheckBox
        function ApplyBrainMaskCheckBoxValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: LightingDropDown
        function LightingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: DenoisingDropDown
        function DenoisingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlipColormapDropDown
        function FlipColormapDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ErodeBaseImageCheckBox
        function ErodeBaseImageCheckBoxValueChanged(app, event)
            value = app.ErodeBaseImageCheckBox.Value
            if value
                app.ErosionStrengthSpinner.Visible = 'on';
                app.ErosionStrengthSpinnerLabel.Visible = 'on';
            else
                app.ErosionStrengthSpinner.Visible = 'off';
                app.ErosionStrengthSpinnerLabel.Visible = 'off';
            end

            if value
                app.ProtectionThresholdSpinner.Visible = 'on';
                app.ProtectionThresholdSpinnerLabel.Visible = 'on';
            else
                app.ProtectionThresholdSpinner.Visible = 'off';
                app.ProtectionThresholdSpinnerLabel.Visible = 'on';
            end
            app.updateVisualization();
        end

        % Value changed function: ErosionStrengthSpinner
        function ErosionStrengthSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ContrastSpinner
        function ContrastSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            %% --- Ask for base filename
            prompt = {'Enter base filename (no extension):'};
            dlgtitle = 'Export PNG';
            definput = {'viewer_snapshot'};
            answer = inputdlg(prompt, dlgtitle, [1 50], definput);
            if isempty(answer), return; end

            baseFilename = strtrim(answer{1});

            %% --- Choose output directory
            savePath = uigetdir(pwd, 'Choose folder to save PNGs');
            if isequal(savePath, 0), return; end

            %% --- Get export info
            cmapName = strrep(app.ColormapDropDown.Value, ' ', '_');  % avoid spaces
            vals  = app.BaseSlider.Value;
            minVal = vals(1);
            maxVal = vals(2);
            %% --- Capture entire app figure
            frame = getframe(app.UIFigure);
            figImage = frame.cdata;

            %% --- Helper: Crop and remove 1px border
            removeBorder = @(img) img(9:end-8, 9:end-8, :);

            %% --- Crop viewer panel
            visPos = round(app.VisualizationPanel.Position);
            yViewer = size(figImage, 1) - visPos(2) - visPos(4);
            viewerCrop = imcrop(figImage, [visPos(1), yViewer, visPos(3)-1, visPos(4)-1]);
            viewerCrop = removeBorder(viewerCrop);  % Remove edge

            % Save viewer as high-res
            viewerFilename = sprintf('%s_viewer_%s.png', baseFilename, cmapName);
            viewerPath = fullfile(savePath, viewerFilename);
            imwrite(imresize(viewerCrop, 2), viewerPath, 'png');  % Upscale ×2 for quality

            %% --- Crop colorbar panel
            % cbarPos = round(app.ColorbarPanel.Position);
            % yCbar = size(figImage, 1) - cbarPos(2) - cbarPos(4);
            % cbarCrop = imcrop(figImage, [cbarPos(1), yCbar, cbarPos(3)-1, cbarPos(4)-1]);
            % cbarCrop = removeBorder(cbarCrop);
            % colorbarFilename = fullfile(savePath, cmapName);
            % imwrite(imresize(cbarCrop, 2), colorbarFilename, 'png');

            %% --- Done!
            uialert(app.UIFigure, 'Export complete', 'Success');
        end

        % Value changed function: ProtectionThresholdSpinner
        function ProtectionThresholdSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ColormapDropDownOverlay
        function ColormapDropDownOverlayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlipColormapDropDownOvelay
        function FlipColormapDropDownOvelayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: RenderingDropDownOverlay
        function RenderingDropDownOverlayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changing function: ThresholdSliderOverlay
        function ThresholdSliderOverlayValueChanging(app, event)
            app.updateVisualization();
        end

        % Value changed function: BaseAlphaSpinner
        function BaseAlphaSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: OverlayAlphaSpinner
        function OverlayAlphaSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ORI1Slider
        function ORI1SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI1_low.Value = round(app.ORI1Slider.Value(1));
            app.ORI1_high.Value = round(app.ORI1Slider.Value(2));
        end

        % Value changed function: ORI2Slider
        function ORI2SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI2_low.Value = round(app.ORI2Slider.Value(1));
            app.ORI2_high.Value = round(app.ORI2Slider.Value(2));
        end

        % Value changed function: ORI3Slider
        function ORI3SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI3_low.Value = round(app.ORI3Slider.Value(1));
            app.ORI3_high.Value = round(app.ORI3Slider.Value(2));
        end

        % Value changed function: ORI1_low
        function ORI1_lowValueChanged(app, event)
            value = app.ORI1_low.Value;
            if value > app.ORI1_high.Value
                app.ORI1_low.Value = floor(app.ORI1_high.Value-1);
                app.ORI1Slider.Value(1) = app.ORI1_low.Value;
            else
                app.ORI1Slider.Value(1) = app.ORI1_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI2_low
        function ORI2_lowValueChanged(app, event)
            value = app.ORI2_low.Value;
            if value > app.ORI2_high.Value
                app.ORI2_low.Value = floor(app.ORI2_high.Value-1);
                app.ORI2Slider.Value(1) = app.ORI2_low.Value;
            else
                app.ORI2Slider.Value(1) = app.ORI2_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI3_low
        function ORI3_lowValueChanged(app, event)
            value = app.ORI3_low.Value;
            if value > app.ORI3_high.Value
                app.ORI3_low.Value = floor(app.ORI3_high.Value-1);
                app.ORI3Slider.Value(1) = app.ORI3_low.Value;
            else
                app.ORI3Slider.Value(1) = app.ORI3_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI1_high
        function ORI1_highValueChanged(app, event)
            value = app.ORI1_high.Value;
            if value < app.ORI1_low.Value
                app.ORI1_high.Value = floor(app.ORI1_low.Value+1);
                app.ORI1Slider.Value(2) = app.ORI1_high.Value;
            else
                app.ORI1Slider.Value(2) = app.ORI1_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI2_high
        function ORI2_highValueChanged(app, event)
            value = app.ORI2_high.Value;
            if value < app.ORI2_low.Value
                app.ORI2_high.Value = floor(app.ORI2_low.Value+1);
                app.ORI2Slider.Value(2) = app.ORI2_high.Value;
            else
                app.ORI2Slider.Value(2) = app.ORI2_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI3_high
        function ORI3_highValueChanged(app, event)
            value = app.ORI3_high.Value;
            if value < app.ORI3_low.Value
                app.ORI3_high.Value = floor(app.ORI3_low.Value+1);
                app.ORI3Slider.Value(2) = app.ORI3_high.Value;
            else
                app.ORI3Slider.Value(2) = app.ORI3_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: Base_low
        function Base_lowValueChanged(app, event)
            value = app.Base_low.Value;
            app.ThresholdSlider.Value(1) = value;
            app.updateVisualization();
        end

        % Value changed function: Base_high
        function Base_highValueChanged(app, event)
            value = app.Base_high.Value;
            app.ThresholdSlider.Value(2) = value;
            app.updateVisualization();
        end

        % Value changed function: Overlay_low
        function Overlay_lowValueChanged(app, event)
            value = app.Overlay_low.Value;
            app.ThresholdSliderOverlay.Value(1) = value;
            app.updateVisualization();
        end

        % Value changed function: Overlay_high
        function Overlay_highValueChanged(app, event)
            value = app.Overlay_high.Value;
            app.ThresholdSliderOverlay.Value(2) = value;
            app.updateVisualization();
        end

        % Value changed function: ApplySliceMaskDropDown
        function ApplySliceMaskDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: TrimBaseCheckBox
        function TrimBaseCheckBoxValueChanged(app, event)
            value = app.TrimBaseCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Base.Visible = "on";
                app.PctHighEditField_Base.Visible = "on";
                app.PctHighBase.Visible = "on";
                app.PctLowBase.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.dataImage = clipOutliers(app.sourceDataImage, app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Base.Visible = "off";
                app.PctHighEditField_Base.Visible = "off";
                app.PctHighBase.Visible = "off";
                app.PctLowBase.Visible = "off";
                app.dataImage = app.sourceDataImage;
            end
            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.dataImage);
            edges           = linspace(min(app.dataImage(valid)),max(app.dataImage(valid)),nBins+1);
            app.baseHistCounts = histcounts(app.dataImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.baseHistEdges = edges;

            app.updateVisualization();
        end

        % Value changed function: TrimOverlayCheckBox
        function TrimOverlayCheckBoxValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                app.PctHighOverlay.Visible = "on";
                app.PctLowOverlay.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.overlayImage = clipOutliers(app.sourceOverlayImage, app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.PctHighOverlay.Visible = "off";
                app.PctLowOverlay.Visible = "off";
                app.overlayImage = app.sourceOverlayImage;
            end
            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.overlayImage);
            edges           = linspace(min(app.overlayImage(valid)),max(app.overlayImage(valid)),nBins+1);
            app.overlayHistCounts = histcounts(app.overlayImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.overlayHistEdges = edges;

            app.updateVisualization();

        end

        % Value changed function: PctLowEditField_Base
        function PctLowEditField_BaseValueChanged(app, event)
            value = app.TrimBaseCheckBox.Value;
            if value
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.dataImage = clipOutliers(app.sourceDataImage, app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);
                % -----------------------------------------------------------------------
            else
                app.dataImage = app.sourceDataImage;
            end
            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.dataImage);
            edges           = linspace(min(app.dataImage(valid)),max(app.dataImage(valid)),nBins+1);
            app.baseHistCounts = histcounts(app.dataImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.baseHistEdges = edges;

            app.updateVisualization();
        end

        % Value changed function: PctHighEditField_Base
        function PctHighEditField_BaseValueChanged(app, event)
            value = app.TrimBaseCheckBox.Value;
            if value
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.dataImage = clipOutliers(app.sourceDataImage, app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);
                % -----------------------------------------------------------------------
            else
                app.dataImage = app.sourceDataImage;
            end
            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.dataImage);
            edges           = linspace(min(app.dataImage(valid)),max(app.dataImage(valid)),nBins+1);
            app.baseHistCounts = histcounts(app.dataImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.baseHistEdges = edges;

            app.updateVisualization();
        end

        % Value changed function: PctLowEditField_Overlay
        function PctLowEditField_OverlayValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.overlayImage = clipOutliers(app.sourceOverlayImage, app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.overlayImage = app.sourceOverlayImage;
            end
            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.overlayImage);
            edges           = linspace(min(app.overlayImage(valid)),max(app.overlayImage(valid)),nBins+1);
            app.overlayHistCounts = histcounts(app.overlayImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.overlayHistEdges = edges;

            app.updateVisualization();

        end

        % Value changed function: PctHighEditField_Overlay
        function PctHighEditField_OverlayValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.overlayImage = clipOutliers(app.sourceOverlayImage, app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.overlayImage = app.sourceOverlayImage;
            end
            % -------------- Calculate Histogram -----------------
            nBins = 256;
            valid           = isfinite(app.overlayImage);
            edges           = linspace(min(app.overlayImage(valid)),max(app.overlayImage(valid)),nBins+1);
            app.overlayHistCounts = histcounts(app.overlayImage(valid),edges).';   % 256×1
            % keep the *global* edges in case you ever need them
            app.overlayHistEdges = edges;

            app.updateVisualization();

        end

        % Value changed function: BaseHighVal
        function BaseHighValValueChanged(app, event)
            rng  = app.BaseSlider.UserData;
            low  = app.BaseLowVal.Value;
            high = app.BaseHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.BaseLowVal.Value  = round(low,2);      % (may have been clipped)
            app.BaseHighVal.Value = round(high,2);

            app.BaseSlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();

        end

        % Value changed function: BaseLowVal
        function BaseLowValValueChanged(app, event)
            rng  = app.BaseSlider.UserData;
            low  = app.BaseLowVal.Value;
            high = app.BaseHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.BaseLowVal.Value  = round(low,2);      % (may have been clipped)
            app.BaseHighVal.Value = round(high,2);

            app.BaseSlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();

        end

        % Value changed function: OverlayLowVal
        function OverlayLowValValueChanged(app, event)
           rng  = app.OverlaySlider.UserData;
            low  = app.OverlayLowVal.Value;
            high = app.OverlayHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.OverlayLowVal.Value  = round(low,2);      % (may have been clipped)
            app.OverlayHighVal.Value = round(high,2);

            app.OverlaySlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();
            
        end

        % Value changed function: OverlayHighVal
        function OverlayHighValValueChanged(app, event)
            rng  = app.OverlaySlider.UserData;
            low  = app.OverlayLowVal.Value;
            high = app.OverlayHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.OverlayLowVal.Value  = round(low,2);      % (may have been clipped)
            app.OverlayHighVal.Value = round(high,2);

            app.OverlaySlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadBaseButton = uibutton(app.UIFigure, 'push');
            app.LoadBaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBaseButtonPushed, true);
            app.LoadBaseButton.Tooltip = {'Load base image. If there is a mask present in the same folder it will also be loaded but it must contain ''*_brain_mask.nii*'' - can also ne .nii.gz'};
            app.LoadBaseButton.Text = 'Load Base';
            app.ColorMapDropDownLabel = uilabel(app.UIFigure);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'Inferno', 'RdYlBl', 'RdYlGn', 'Turbo', 'Spectral', 'Devon', 'grayC', 'Hawaii', 'Viridis', 'Plasma', 'Magma', 'Lajolla', 'Buda', 'Nuuk', 'Roma', 'DSA'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Inferno';
            app.ThresholdSlider = uislider(app.UIFigure, 'range');
            app.ThresholdSlider.Limits = [1 2];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Visible = 'off';
            app.ThresholdSlider.Tooltip = {'The slider determines which data from the base image is made visible. Any values outside the high and low range are not visible. This slider has no effect on color scaling. '};
            app.ThresholdSlider.Value = [1 2];
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.SizeChangedFcn = createCallbackFcn(app, @VisualizationPanelSizeChanged, true);
            app.TerritoryDropDownLabel = uilabel(app.UIFigure);
            app.TerritoryDropDownLabel.Visible = 'off';
            app.TerritoryDropDownLabel.Text = 'Territory';
            app.TerritoryDropDown = uidropdown(app.UIFigure);
            app.TerritoryDropDown.Items = {'ALL', 'Anterior_L', 'Anterior_R', 'PCA', 'VB', 'Ventricles'};
            app.TerritoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TerritoryDropDownValueChanged, true);
            app.TerritoryDropDown.Visible = 'off';
            app.TerritoryDropDown.Value = 'ALL';
            app.RenderingDropDownLabel = uilabel(app.UIFigure);
            app.RenderingDropDownLabel.Text = 'Rendering';
            app.RenderingDropDown = uidropdown(app.UIFigure);
            app.RenderingDropDown.Items = {'CinematicRendering', 'VolumeRendering', 'LightScattering', 'GradientOpacity', 'Isosurface'};
            app.RenderingDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingDropDownValueChanged, true);
            app.RenderingDropDown.Tooltip = {'good options are cinematic and light scattering'};
            app.RenderingDropDown.Value = 'CinematicRendering';
            app.ColorbarPanel = uipanel(app.UIFigure);
            app.ColorbarPanel.Visible = 'off';
            app.ApplyBrainMaskCheckBox = uicheckbox(app.UIFigure);
            app.ApplyBrainMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyBrainMaskCheckBoxValueChanged, true);
            app.ApplyBrainMaskCheckBox.Visible = 'off';
            app.ApplyBrainMaskCheckBox.Text = 'Apply Brain Mask';
            app.BackgroundDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.UIFigure);
            app.BackgroundDropDown.Items = {'Dark', 'Light'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Tooltip = {'switch between dark and light background for visibility'};
            app.BackgroundDropDown.Value = 'Dark';
            app.LightingDropDownLabel = uilabel(app.UIFigure);
            app.LightingDropDownLabel.Text = 'Lighting';
            app.LightingDropDown = uidropdown(app.UIFigure);
            app.LightingDropDown.Items = {'on', 'off'};
            app.LightingDropDown.ValueChangedFcn = createCallbackFcn(app, @LightingDropDownValueChanged, true);
            app.LightingDropDown.Tooltip = {'Adds lighting, GPU intensive'};
            app.LightingDropDown.Value = 'off';
            app.DenoisingDropDownLabel = uilabel(app.UIFigure);
            app.DenoisingDropDownLabel.Text = 'Denoising';
            app.DenoisingDropDown = uidropdown(app.UIFigure);
            app.DenoisingDropDown.Items = {'on', 'off'};
            app.DenoisingDropDown.ValueChangedFcn = createCallbackFcn(app, @DenoisingDropDownValueChanged, true);
            app.DenoisingDropDown.Tooltip = {'Denoises volume, GPU intensive'};
            app.DenoisingDropDown.Value = 'off';
            app.FlipMapLabel = uilabel(app.UIFigure);
            app.FlipMapLabel.Text = 'Flip Map';
            app.FlipColormapDropDown = uidropdown(app.UIFigure);
            app.FlipColormapDropDown.Items = {'no', 'yes'};
            app.FlipColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @FlipColormapDropDownValueChanged, true);
            app.FlipColormapDropDown.Tooltip = {'inverts colormap'};
            app.FlipColormapDropDown.Value = 'no';
            app.ErosionStrengthSpinnerLabel = uilabel(app.UIFigure);
            app.ErosionStrengthSpinnerLabel.Visible = 'off';
            app.ErosionStrengthSpinnerLabel.Text = 'Erosion Strength';
            app.ErosionStrengthSpinner = uispinner(app.UIFigure);
            app.ErosionStrengthSpinner.Step = 0.025;
            app.ErosionStrengthSpinner.Limits = [0.025 1];
            app.ErosionStrengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ErosionStrengthSpinnerValueChanged, true);
            app.ErosionStrengthSpinner.Visible = 'off';
            app.ErosionStrengthSpinner.Tooltip = {'controls the amount of erosion - this can then be tuned with the protection threshold'};
            app.ErosionStrengthSpinner.Value = 0.1;
            app.ErodeBaseImageCheckBox = uicheckbox(app.UIFigure);
            app.ErodeBaseImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErodeBaseImageCheckBoxValueChanged, true);
            app.ErodeBaseImageCheckBox.Tooltip = {'The base image can be eroded considering a protection threshold to preserve structures of specified sizes. This was intended for MRA data.'};
            app.ErodeBaseImageCheckBox.Text = 'Erode Base Image';
            app.ExportImageButton = uibutton(app.UIFigure, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export Image';
            app.ProtectionThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ProtectionThresholdSpinnerLabel.Visible = 'off';
            app.ProtectionThresholdSpinnerLabel.Text = 'Protection Threshold';
            app.ProtectionThresholdSpinner = uispinner(app.UIFigure);
            app.ProtectionThresholdSpinner.Limits = [1 5];
            app.ProtectionThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ProtectionThresholdSpinnerValueChanged, true);
            app.ProtectionThresholdSpinner.Visible = 'off';
            app.ProtectionThresholdSpinner.Tooltip = {'Protective erosion will maintain vessels - larger value means more vessels'};
            app.ProtectionThresholdSpinner.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MRAwesome.png');
            app.ContrastSpinnerLabel = uilabel(app.UIFigure);
            app.ContrastSpinnerLabel.Visible = 'off';
            app.ContrastSpinnerLabel.Text = 'Contrast';
            app.ContrastSpinner = uispinner(app.UIFigure);
            app.ContrastSpinner.Step = 0.2;
            app.ContrastSpinner.Limits = [0 3];
            app.ContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @ContrastSpinnerValueChanged, true);
            app.ContrastSpinner.Visible = 'off';
            app.ContrastSpinner.Tooltip = {'modifies the alpha value - higher values give lighter vessels with more contrast'};
            app.ContrastSpinner.Value = 1;
            app.LoadOverlayButton = uibutton(app.UIFigure, 'push');
            app.LoadOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOverlayButtonPushed, true);
            app.LoadOverlayButton.Visible = 'off';
            app.LoadOverlayButton.Tooltip = {'Loads an overlay image. The overlay will be resampled and interpolated to the dimensions of the base image. '};
            app.LoadOverlayButton.Text = 'Load Overlay';
            app.OverlayMapLabel = uilabel(app.UIFigure);
            app.OverlayMapLabel.Visible = 'off';
            app.OverlayMapLabel.Text = 'Overlay Map';
            app.ColormapDropDownOverlay = uidropdown(app.UIFigure);
            app.ColormapDropDownOverlay.Items = {'Inferno', 'RdYlBl', 'RdYlGn', 'Turbo', 'Spectral', 'Devon', 'grayC', 'Hawaii', 'Viridis', 'Plasma', 'Magma', 'Lajolla', 'Buda', 'Nuuk', 'Roma', 'DSA'};
            app.ColormapDropDownOverlay.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownOverlayValueChanged, true);
            app.ColormapDropDownOverlay.Visible = 'off';
            app.ColormapDropDownOverlay.Value = 'Viridis';
            app.FlipMapLabel_2 = uilabel(app.UIFigure);
            app.FlipMapLabel_2.Tag = 'Label';
            app.FlipMapLabel_2.Visible = 'off';
            app.FlipMapLabel_2.Text = 'Flip Map';
            app.FlipColormapDropDownOvelay = uidropdown(app.UIFigure);
            app.FlipColormapDropDownOvelay.Items = {'no', 'yes'};
            app.FlipColormapDropDownOvelay.ValueChangedFcn = createCallbackFcn(app, @FlipColormapDropDownOvelayValueChanged, true);
            app.FlipColormapDropDownOvelay.Visible = 'off';
            app.FlipColormapDropDownOvelay.Tooltip = {'inverts colormap'};
            app.FlipColormapDropDownOvelay.Value = 'no';
            app.ColorbarPanelOverlay = uipanel(app.UIFigure);
            app.ColorbarPanelOverlay.Visible = 'off';
            app.ThresholdSliderOverlay = uislider(app.UIFigure, 'range');
            app.ThresholdSliderOverlay.Limits = [1 2];
            app.ThresholdSliderOverlay.MajorTicks = [1 1.2 1.4 1.6 1.8 2];
            app.ThresholdSliderOverlay.MajorTickLabels = {'1', '1.2', '1.4', '1.6', '1.8', '2'};
            app.ThresholdSliderOverlay.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderOverlayValueChanged, true);
            app.ThresholdSliderOverlay.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderOverlayValueChanging, true);
            app.ThresholdSliderOverlay.MinorTicks = [];
            app.ThresholdSliderOverlay.Visible = 'off';
            app.ThresholdSliderOverlay.Tooltip = {'The slider determines which data from the base image is made visible. Any values outside the high and low range are not visible. This slider has no effect on color scaling. '};
            app.ThresholdSliderOverlay.Value = [1 2];
            app.BaseThresholdLabel = uilabel(app.UIFigure);
            app.BaseThresholdLabel.Visible = 'off';
            app.BaseThresholdLabel.Text = 'Base Threshold';
            app.RenderingDropDown_2Label = uilabel(app.UIFigure);
            app.RenderingDropDown_2Label.Visible = 'off';
            app.RenderingDropDown_2Label.Text = 'Rendering';
            app.RenderingDropDownOverlay = uidropdown(app.UIFigure);
            app.RenderingDropDownOverlay.Items = {'VolumeOverlay', 'GradientOverlay', 'LabelOverlay'};
            app.RenderingDropDownOverlay.ValueChangedFcn = createCallbackFcn(app, @RenderingDropDownOverlayValueChanged, true);
            app.RenderingDropDownOverlay.Visible = 'off';
            app.RenderingDropDownOverlay.Tooltip = {''};
            app.RenderingDropDownOverlay.Value = 'VolumeOverlay';
            app.BaseSlider = uislider(app.UIFigure, 'range');
            app.BaseSlider.Limits = [0 1];
            app.BaseSlider.MajorTicks = [];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.MinorTicks = [];
            app.BaseSlider.Visible = 'off';
            app.BaseSlider.Tooltip = {'This slider can be used to scale the colormap. The high and low slider values do not currently align with the histogram shown in the colorbar - this should only bse used as a reference.'};
            app.BaseSlider.Value = [0 1];
            app.OverlaySlider = uislider(app.UIFigure, 'range');
            app.OverlaySlider.Limits = [0 1];
            app.OverlaySlider.MajorTicks = [];
            app.OverlaySlider.ValueChangedFcn = createCallbackFcn(app, @OverlaySliderValueChanged, true);
            app.OverlaySlider.MinorTicks = [];
            app.OverlaySlider.Visible = 'off';
            app.OverlaySlider.Tooltip = {'This slider can be used to scale the colormap. The high and low slider values do not currently align with the histogram shown in the colorbar - this should only bse used as a reference.'};
            app.OverlaySlider.Value = [0 1];
            app.OverlayThresholdLabel = uilabel(app.UIFigure);
            app.OverlayThresholdLabel.Visible = 'off';
            app.OverlayThresholdLabel.Text = 'Overlay Threshold';
            app.BaseAlphaSpinnerLabel = uilabel(app.UIFigure);
            app.BaseAlphaSpinnerLabel.Visible = 'off';
            app.BaseAlphaSpinnerLabel.Text = 'Base Alpha';
            app.BaseAlphaSpinner = uispinner(app.UIFigure);
            app.BaseAlphaSpinner.Step = 0.1;
            app.BaseAlphaSpinner.Limits = [0 1];
            app.BaseAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseAlphaSpinnerValueChanged, true);
            app.BaseAlphaSpinner.Visible = 'off';
            app.BaseAlphaSpinner.Tooltip = {'Sets the transparency of the base image.'};
            app.BaseAlphaSpinner.Value = 0.9;
            app.OverlayAlphaSpinnerLabel = uilabel(app.UIFigure);
            app.OverlayAlphaSpinnerLabel.Visible = 'off';
            app.OverlayAlphaSpinnerLabel.Text = 'Overlay Alpha';
            app.OverlayAlphaSpinner = uispinner(app.UIFigure);
            app.OverlayAlphaSpinner.Step = 0.1;
            app.OverlayAlphaSpinner.Limits = [0 1];
            app.OverlayAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlayAlphaSpinnerValueChanged, true);
            app.OverlayAlphaSpinner.Visible = 'off';
            app.OverlayAlphaSpinner.Tooltip = {'Sets the transparency of the overlay image.'};
            app.OverlayAlphaSpinner.Value = 0.5;
            app.ORI1SliderLabel = uilabel(app.UIFigure);
            app.ORI1SliderLabel.Visible = 'off';
            app.ORI1SliderLabel.Text = 'ORI1';
            app.ORI1Slider = uislider(app.UIFigure, 'range');
            app.ORI1Slider.Limits = [0 1];
            app.ORI1Slider.MajorTicks = [];
            app.ORI1Slider.ValueChangedFcn = createCallbackFcn(app, @ORI1SliderValueChanged, true);
            app.ORI1Slider.MinorTicks = [];
            app.ORI1Slider.Visible = 'off';
            app.ORI1Slider.Value = [0 1];
            app.ORI2SliderLabel = uilabel(app.UIFigure);
            app.ORI2SliderLabel.Visible = 'off';
            app.ORI2SliderLabel.Text = 'ORI2';
            app.ORI2Slider = uislider(app.UIFigure, 'range');
            app.ORI2Slider.Limits = [0 1];
            app.ORI2Slider.MajorTicks = [];
            app.ORI2Slider.ValueChangedFcn = createCallbackFcn(app, @ORI2SliderValueChanged, true);
            app.ORI2Slider.MinorTicks = [];
            app.ORI2Slider.Visible = 'off';
            app.ORI2Slider.Value = [0 1];
            app.ORI3SliderLabel = uilabel(app.UIFigure);
            app.ORI3SliderLabel.Visible = 'off';
            app.ORI3SliderLabel.Text = 'ORI3';
            app.ORI3Slider = uislider(app.UIFigure, 'range');
            app.ORI3Slider.Limits = [0 1];
            app.ORI3Slider.MajorTicks = [];
            app.ORI3Slider.ValueChangedFcn = createCallbackFcn(app, @ORI3SliderValueChanged, true);
            app.ORI3Slider.MinorTicks = [];
            app.ORI3Slider.Visible = 'off';
            app.ORI3Slider.Value = [0 1];
            app.ORI1_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI1_low.ValueChangedFcn = createCallbackFcn(app, @ORI1_lowValueChanged, true);
            app.ORI2_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI2_low.ValueChangedFcn = createCallbackFcn(app, @ORI2_lowValueChanged, true);
            app.ORI3_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI3_low.ValueChangedFcn = createCallbackFcn(app, @ORI3_lowValueChanged, true);
            app.ORI1_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI1_high.ValueChangedFcn = createCallbackFcn(app, @ORI1_highValueChanged, true);
            app.ORI2_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI2_high.ValueChangedFcn = createCallbackFcn(app, @ORI2_highValueChanged, true);
            app.ORI3_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI3_high.ValueChangedFcn = createCallbackFcn(app, @ORI3_highValueChanged, true);
            app.Base_low = uieditfield(app.UIFigure, 'numeric');
            app.Base_low.ValueChangedFcn = createCallbackFcn(app, @Base_lowValueChanged, true);
            app.Base_low.Visible = 'off';
            app.Overlay_low = uieditfield(app.UIFigure, 'numeric');
            app.Overlay_low.ValueChangedFcn = createCallbackFcn(app, @Overlay_lowValueChanged, true);
            app.Overlay_low.Visible = 'off';
            app.Base_high = uieditfield(app.UIFigure, 'numeric');
            app.Base_high.ValueChangedFcn = createCallbackFcn(app, @Base_highValueChanged, true);
            app.Base_high.Visible = 'off';
            app.Overlay_high = uieditfield(app.UIFigure, 'numeric');
            app.Overlay_high.ValueChangedFcn = createCallbackFcn(app, @Overlay_highValueChanged, true);
            app.Overlay_high.Visible = 'off';
            app.ApplySliceMaskDropDownLabel = uilabel(app.UIFigure);
            app.ApplySliceMaskDropDownLabel.Text = 'Apply Slice Mask';
            app.ApplySliceMaskDropDown = uidropdown(app.UIFigure);
            app.ApplySliceMaskDropDown.Items = {'All'};
            app.ApplySliceMaskDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplySliceMaskDropDownValueChanged, true);
            app.ApplySliceMaskDropDown.Tooltip = {'When loading a base and overlay image, the user can specify to which images the slice masks are applied. '};
            app.ApplySliceMaskDropDown.Value = 'All';
            app.TrimBaseCheckBox = uicheckbox(app.UIFigure);
            app.TrimBaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimBaseCheckBoxValueChanged, true);
            app.TrimBaseCheckBox.Visible = 'off';
            app.TrimBaseCheckBox.Tooltip = {'This will remove the high and low percentile of data values as specified in the Pct Low and Pct High edit fields.'};
            app.TrimBaseCheckBox.Text = 'Trim Base';
            app.TrimOverlayCheckBox = uicheckbox(app.UIFigure);
            app.TrimOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimOverlayCheckBoxValueChanged, true);
            app.TrimOverlayCheckBox.Visible = 'off';
            app.TrimOverlayCheckBox.Tooltip = {'This will remove the high and low percentile of data values as specified in the Pct Low and Pct High edit fields.'};
            app.TrimOverlayCheckBox.Text = 'Trim Overlay';
            app.PctLowEditField_Base = uieditfield(app.UIFigure, 'numeric');
            app.PctLowEditField_Base.Limits = [0 100];
            app.PctLowEditField_Base.ValueChangedFcn = createCallbackFcn(app, @PctLowEditField_BaseValueChanged, true);
            app.PctLowEditField_Base.Visible = 'off';
            app.PctLowEditField_Base.Value = 0.01;
            app.PctLowEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.PctLowEditField_Overlay.Limits = [0 100];
            app.PctLowEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @PctLowEditField_OverlayValueChanged, true);
            app.PctLowEditField_Overlay.Visible = 'off';
            app.PctLowEditField_Overlay.Value = 0.01;
            app.PctHighBase = uilabel(app.UIFigure);
            app.PctHighBase.Visible = 'off';
            app.PctHighBase.Text = 'Pct High';
            app.PctHighEditField_Base = uieditfield(app.UIFigure, 'numeric');
            app.PctHighEditField_Base.Limits = [0 100];
            app.PctHighEditField_Base.ValueChangedFcn = createCallbackFcn(app, @PctHighEditField_BaseValueChanged, true);
            app.PctHighEditField_Base.Visible = 'off';
            app.PctHighEditField_Base.Value = 99.99;
            app.PctHighEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.PctHighEditField_Overlay.Limits = [0 100];
            app.PctHighEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @PctHighEditField_OverlayValueChanged, true);
            app.PctHighEditField_Overlay.Visible = 'off';
            app.PctHighEditField_Overlay.Value = 99.99;
            app.PctLowOverlay = uilabel(app.UIFigure);
            app.PctLowOverlay.Visible = 'off';
            app.PctLowOverlay.Text = 'Pct Low';
            app.PctLowBase = uilabel(app.UIFigure);
            app.PctLowBase.Visible = 'off';
            app.PctLowBase.Text = 'Pct Low';
            app.PctHighOverlay = uilabel(app.UIFigure);
            app.PctHighOverlay.Visible = 'off';
            app.PctHighOverlay.Text = 'Pct High';
            app.BaseHighVal = uieditfield(app.UIFigure, 'numeric');
            app.BaseHighVal.ValueChangedFcn = createCallbackFcn(app, @BaseHighValValueChanged, true);
            app.BaseHighVal.Visible = 'off';
            app.BaseLowVal = uieditfield(app.UIFigure, 'numeric');
            app.BaseLowVal.ValueChangedFcn = createCallbackFcn(app, @BaseLowValValueChanged, true);
            app.BaseLowVal.Visible = 'off';
            app.OverlayLowVal = uieditfield(app.UIFigure, 'numeric');
            app.OverlayLowVal.ValueChangedFcn = createCallbackFcn(app, @OverlayLowValValueChanged, true);
            app.OverlayLowVal.Visible = 'off';
            app.OverlayHighVal = uieditfield(app.UIFigure, 'numeric');
            app.OverlayHighVal.ValueChangedFcn = createCallbackFcn(app, @OverlayHighValValueChanged, true);
            app.OverlayHighVal.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seeVieweR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end